classdef Aplikacja < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        UIAxes                         matlab.ui.control.UIAxes
        Slider                         matlab.ui.control.Slider
        Krok1WybrzdjipniapucnegoLabel  matlab.ui.control.Label
        Rekonstrukcja3DoskrzeliLabel   matlab.ui.control.Label
        GraniceprzedziauLabel          matlab.ui.control.Label
        DodajFolderButton              matlab.ui.control.Button
        WytnijButton                   matlab.ui.control.Button
        Krok2WysegmentowaniepucLabel   matlab.ui.control.Label
        Krok3WysegmentowanieoskrzeliLabel  matlab.ui.control.Label
        Slider_2                       matlab.ui.control.Slider
        Slider_3                       matlab.ui.control.Slider
        GenerujButton                  matlab.ui.control.Button
        GenerujButton_2                matlab.ui.control.Button
        Generujobraz3DButton           matlab.ui.control.Button
        WybierzKolorDropDownLabel      matlab.ui.control.Label
        WybierzKolorDropDown           matlab.ui.control.DropDown
        UIAxes2                        matlab.ui.control.UIAxes
        OknointensywnociLabel          matlab.ui.control.Label
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes4                        matlab.ui.control.UIAxes
        Slider_4                       matlab.ui.control.Slider
        AktualnyobrazEditField_4Label  matlab.ui.control.Label
        AktualnyobrazEditField_4       matlab.ui.control.NumericEditField
        minSpinnerLabel                matlab.ui.control.Label
        minSpinner                     matlab.ui.control.Spinner
        maxSpinnerLabel                matlab.ui.control.Label
        maxSpinner                     matlab.ui.control.Spinner
        MinSpinnerLabel                matlab.ui.control.Label
        MinSpinner                     matlab.ui.control.Spinner
        MaxSpinnerLabel                matlab.ui.control.Label
        MaxSpinner                     matlab.ui.control.Spinner
        zLabel                         matlab.ui.control.Label
        AktualnyobrazSpinnerLabel      matlab.ui.control.Label
        AktualnyobrazSpinner           matlab.ui.control.Spinner
        ThresholdSpinnerLabel          matlab.ui.control.Label
        ThresholdSpinner               matlab.ui.control.Spinner
        AktualnyobrazSpinner_2Label    matlab.ui.control.Label
        AktualnyobrazSpinner_2         matlab.ui.control.Spinner
        ThresholdSpinner_2Label        matlab.ui.control.Label
        ThresholdSpinner_2             matlab.ui.control.Spinner
        rowSpinnerLabel                matlab.ui.control.Label
        rowSpinner                     matlab.ui.control.Spinner
        columnSpinnerLabel             matlab.ui.control.Label
        columnSpinner                  matlab.ui.control.Spinner
        toleranceSpinnerLabel          matlab.ui.control.Label
        toleranceSpinner               matlab.ui.control.Spinner
        Slider_5                       matlab.ui.control.Slider
        ResetujButton                  matlab.ui.control.Button
        WysegmentujpiepucnyButton      matlab.ui.control.Button
        Krok4SegmentacjapniapucnegoLabel  matlab.ui.control.Label
        AktualnyobrazEditField_5Label  matlab.ui.control.Label
        AktualnyobrazEditField_5       matlab.ui.control.NumericEditField
        RnicaIntensywnociSpinnerLabel  matlab.ui.control.Label
        RnicaIntensywnociSpinner       matlab.ui.control.Spinner
        Lamp2                          matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        %zmienne globalne
        dname;
        obrazek;
        obraz; 
        first;
        second;
        s;
        xi;
        yi;
        XY;
        aktualny;
        full;
        nowy3;
        Krok=0;
    end
    
    properties (Access = public)
        % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DodajFolderButton
        function dodajFolder(app, event)
            %uruchomienie
            app.Lamp2.Color='red';
            app.zLabel.Text='Trwa ładowanie zdjęć';
            if(app.Krok>=0)
            try %wybieranie folderu
            app.dname=uigetdir('D:/Projekty_w_Realizacji/Studia/POM/POM_obrazy');
            app.obrazek=dicomread([app.dname,'\IMG00001.dcm']); %pierwszy obraz
            catch
                
                uialert(app.UIFigure,'Błędnie podany folder','Invalid File');
                return; %jesli bład wroc do poczatku
            end
            for i=1 :700
                 m=num2str(i);
            try
                 if(i<10)       
                    x=dicomread([app.dname,'\IMG0000',m,'.dcm']); 
                    app.obraz=cat(3,app.obrazek,x); 
                    %cat łaczy tablice w tym przypadku macierz obrazu
                    app.obrazek=+app.obraz; 
                 end
                 if(i<100)
                     x=dicomread([app.dname,'\IMG000',m,'.dcm']);  
                    app.obraz=cat(3,app.obraz,x);
                 else   
                     x=dicomread([app.dname,'\IMG00',m,'.dcm']); 
                     app.obraz=cat(3,app.obraz,x);
                end
            catch
            end
            end
            try
                app.zLabel.Text='Połączono zdjęcia'
                       V=im2single(int16(app.obraz));
                       imagesize=size(app.obraz);
                       maxLimit=imagesize(3);
                       app.Slider.Limits=[1 maxLimit];
            catch

            end
                    V=im2single(int16(app.obraz));
                     imagesize=size(app.obraz);
                        maxLimit=imagesize(3);
                             app.Slider.Limits=[1 maxLimit];
            imshow(app.obraz(:,:,app.AktualnyobrazEditField_4.Value),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes2)
            app.Krok=1;
            else
            uialert(app.UIFigure,'Coś poszło nie tak, zresetuj ustawienia.','Invalid File');
        end
            app.zLabel.Text='Ładowanie zdjęć zostało ukończone';
            app.Lamp2.Color='green';
        end

        % Button pushed function: WytnijButton
        function wytnij(app, event)
 
            app.Lamp2.Color='red';
            app.zLabel.Text='Trwa ładowanie zdjęć';
            if(app.Krok>=1)
            %%%%%%%%%%%%%%%%%%%%%
            app.obrazek=dicomread([app.dname,'\IMG00001.dcm']);
            
            for i=app.MinSpinner.Value :app.MaxSpinner.Value
             m=num2str(i);
            try
                  if(i<10)       
                    x=dicomread([app.dname,'\IMG0000',m,'.dcm']); 
                     app.obraz=cat(3,app.obrazek,x); %cat łaczy tablice w tym przypadku macierz obrazu
                     app.obrazek=+app.obraz; 
                    end
                   if(i<100)
                      x=dicomread([app.dname,'\IMG000',m,'.dcm']);  
                       app.obraz=cat(3,app.obraz,x);
                     else   
                         x=dicomread([app.dname,'\IMG00',m,'.dcm']); 
                         app.obraz=cat(3,app.obraz,x);
                   end 
            catch
            end
            end
                       V=im2single(int16(app.obraz));
                       imagesize=size(app.obraz);
                       maxLimit=imagesize(3);
                       app.Slider.Limits=[1 maxLimit];
imshow(app.obraz(:,:,app.AktualnyobrazEditField_4.Value),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes2)
                app.Krok=1;
            else
             uialert(app.UIFigure,'Należy najpierw wykonać wcześniejsze kroki.','Warning','Icon','warning');
            end
            app.zLabel.Text='Zdjęcia zostały załadowane';
            app.Lamp2.Color='green';
        end

        % Value changed function: Slider
        function suwak1(app, event)
                          value = app.Slider.Value;
                           try
                           imagesize=size(app.obraz);
                           maxLimit=imagesize(3);
                           app.Slider.Limits=[1 maxLimit];
                           app.AktualnyobrazEditField_4.Value=ceil(value);
                           imshow(app.obraz(:,:,ceil(value)),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes2)
                           catch
                                uialert(app.UIFigure,'Błąd, Wartość poza zakresem','Invalid File');
                           end
                           try
                                 app.AktualnyobrazEditField_4.Value=ceil(value);
                           catch
                           end
                        
        end

        % Button pushed function: GenerujButton
        function generuj1(app, event)
            app.Lamp2.Color='red';
            app.zLabel.Text='Generowanie maski';
          if(app.Krok>=1)
           try
                XY=app.obraz(:,:,1);
                imagesize=size(app.obraz);
                maxLimit=imagesize(3);
                app.AktualnyobrazSpinner.Limits=[1 maxLimit]
                t=app.ThresholdSpinner.Value;
               % XY=int16(XY);
                app.first=segmentImageXY(XY,t); 
           catch
                uialert(app.UIFigure,'Nie załadowano zdjęć','Invalid File');
           end
        %preprocessing dla kolejnych warstw
        app.zLabel.Text='Progowanie i segmentowanie dla każdej warstwy'
            for i=app.MinSpinner.Value:1:maxLimit+1  
                try
               
                XY=app.obraz(:,:,i);
                XYmask=segmentImageXY(XY,t);
                JY=int16(XYmask);
                obY=XY.*JY;
                maska3D=cat(3,app.first,obY); 
                app.first=+maska3D;
                catch
                end
            end
            imshow(app.first(:,:,app.AktualnyobrazSpinner.Value),[],'Parent',app.UIAxes3);
            app.Krok=2;
          else
     uialert(app.UIFigure,'Należy najpierw wykonać wcześniejsze kroki.','Warning','Icon','warning');
          end
        app.zLabel.Text='Zakończono generowanie maski';
        app.Lamp2.Color='green';
        end

        % Button pushed function: GenerujButton_2
        function generuj2(app, event)
            app.Lamp2.Color='red';
            app.zLabel.Text='Trwa generowanie maski';
            if(app.Krok>=2)
            try
            t2=app.ThresholdSpinner_2.Value;     %                  
            row = app.rowSpinner.Value;              
            column = app.columnSpinner.Value;
            tolerance = app.toleranceSpinner.Value;
            imagesize=size(app.first);
            maxLimit=imagesize(3);
            app.AktualnyobrazSpinner_2.Limits=[1 maxLimit]
            rka=app.first(:,:,1);
            app.second=segmentBranchos(rka,t2,row,column,tolerance);
            catch              
            end                            
        for i=1:1:700
          try
            XY=app.first(:,:,i);
            XYmask=segmentBranchos(XY,t2,row,column,tolerance);
            JY=int16(XYmask);
            obY=XY.*JY;
            maska3D=cat(3,app.second,obY); 
            app.second=+maska3D;
          catch
          end
        end
        try
        imshow(app.second(:,:,app.AktualnyobrazSpinner_2.Value),[],'Parent',app.UIAxes4);
        catch
        end
        app.Krok=3;
            else
     uialert(app.UIFigure,'Należy najpierw wykonać wcześniejsze kroki.','Warning','Icon','warning');
            end
        app.zLabel.Text='generowanie maski zakonczone';
            app.Lamp2.Color='green';
        end

        % Button pushed function: Generujobraz3DButton
        function wynikbutton(app, event)
          app.Lamp2.Color='red';
          app.zLabel.Text='trwa process rekonstrukcji'
          if(app.Krok>=3)
            nowy2=im2single(int16(app.second));            
            nowy2=flip(nowy2,3);
                %%
                %Wizualizacja oskrzeli
                app.UIAxes;
                try
                p = patch(app.UIAxes, isosurface(double(nowy2)));
                p.FaceColor = app.WybierzKolorDropDown.Value
                p.EdgeColor = 'none';
                catch
                end
                hold on
                try
                p = patch(app.UIAxes, isosurface(double(app.nowy3)));
                p.FaceColor = app.WybierzKolorDropDown.Value
                p.EdgeColor = 'none';
                catch
                end 
                daspect(app.UIAxes,[1 1 1/2]);
                camlight(app.UIAxes)
                lighting(app.UIAxes,'gouraud')
                axis(app.UIAxes, 'vis3d')
                %lighting flat
                %lighting none
                close
          else
            uialert(app.UIFigure,'Należy najpierw wykonać wcześniejsze kroki.Segmentacja Pnia płucnego nie jest wymagana','Warning','Icon','warning');
          end
                app.zLabel.Text='ukonczono proces rekonstrukcji';
                app.Lamp2.Color='green';

        end

        % Value changed function: Slider_4
        function Progowanie1(app, event)
            value = app.Slider_4.Value;
            app.ThresholdSpinner.Value=value;           
        end

        % Value changing function: Slider
        function suwak1next(app, event)
%             changingValue = event.Value;
%                                    app.AktualnyobrazEditField.Value=changingValue;
%                        imshow(app.obraz(:,:,changingValue),'Parent',app.UIAxes2)
        end

        % Value changed function: minSpinner
        function minIntensive(app, event)
            value = app.minSpinner.Value;
        try
             if(value>=app.maxSpinner.Value)
                 app.maxSpinner.Value=value+1;                          
        else
                        currentImage=app.AktualnyobrazEditField_4.Value;
             imshow(app.obraz(:,:,currentImage),[value app.maxSpinner.Value],'Parent',app.UIAxes2)
             end
        catch
            
        end
     
             
        end

        % Value changed function: maxSpinner
        function maxIntensive(app, event)
            value = app.maxSpinner.Value;
            try
            if(value<=app.minSpinner.Value)
                app.minSpinner.Value=value-1;
            else
                currentImage=app.AktualnyobrazEditField_4.Value;
             imshow(app.obraz(:,:,currentImage),[app.minSpinner.Value value],'Parent',app.UIAxes2)
                
            end
            catch
            end
        end

        % Value changed function: MinSpinner
        function minUsun(app, event)
            value = app.MinSpinner.Value;
                        imagesize=size(app.obraz);
                        maxLimit=imagesize(3);
                        app.Slider.Limits=[1 maxLimit];
            if(value>=app.MaxSpinner.Value-10)
                 app.MaxSpinner.Value=value+10;                
             end
        end

        % Value changed function: MaxSpinner
        function maxUsun(app, event)
            value = app.MaxSpinner.Value;
                        imagesize=size(app.obraz);
                        maxLimit=imagesize(3);
                        app.Slider.Limits=[1 maxLimit];
          if(value<=app.MinSpinner.Value+10)
                app.MinSpinner.Value=value-10;               
            end    
        end

        % Value changed function: ThresholdSpinner
        function threshold(app, event)
            value = app.ThresholdSpinner.Value;
            app.Slider_4.Value=value;
        end

        % Value changed function: Slider_2
        function suwak2(app, event)
            value = app.Slider_2.Value;
                       try
                       imagesize=size(app.first);
                       maxLimit=imagesize(3);
                       app.Slider.Limits=[1 maxLimit];
                       app.AktualnyobrazSpinner.Value=ceil(value);
                       imshow(app.first(:,:,ceil(value)),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes3)
                       catch
                       end
                       try
                             app.AktualnyobrazSpinner.Value=ceil(value);
                       catch
                       end
        end

        % Value changed function: AktualnyobrazSpinner
        function Suwak2zobrazka(app, event)
            value = app.AktualnyobrazSpinner.Value;
            app.Slider_2.Value=value;
                                   imagesize=size(app.first);
                       maxLimit=imagesize(3);
                       app.Slider.Limits=[1 maxLimit];
                       app.AktualnyobrazSpinner.Value=ceil(value);
                       imshow(app.first(:,:,ceil(value)),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes4)
        end

        % Value changed function: Slider_3
        function suwak3(app, event)
            value = app.Slider_3.Value;                          
                      try
                       imagesize=size(app.second);
                       maxLimit=imagesize(3);
                       app.Slider.Limits=[1 maxLimit];
                       app.AktualnyobrazSpinner_2.Value=ceil(value);
                       imshow(app.second(:,:,ceil(value)),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes4)
                       catch
                      end
                             app.AktualnyobrazSpinner_2.Value=ceil(value);
        end

        % Value changed function: AktualnyobrazSpinner_2
        function suwak3zobrazka(app, event)
            value = app.AktualnyobrazSpinner_2.Value;
                        app.Slider_3.Value=value;
                                   imagesize=size(app.second);
                       maxLimit=imagesize(3);
                       app.Slider.Limits=[1 maxLimit];
                       app.AktualnyobrazSpinner_2.Value=ceil(value);
                       imshow(app.second(:,:,ceil(value)),[app.minSpinner.Value app.maxSpinner.Value],'Parent',app.UIAxes4)
        end

        % Value changed function: ThresholdSpinner_2
        function progowaniekrok3(app, event)
            value = app.ThresholdSpinner_2.Value;

            app.Slider_5.Value=value;
        end

        % Value changed function: Slider_5
        function Progowanie3(app, event)
            value = app.Slider_5.Value;     
           app.ThresholdSpinner_2.Value=value;
               
        end

        % Button pushed function: ResetujButton
        function reset(app, event)
            app.Lamp2.Color='green';
            app.zLabel.Text='Zresetowano ustawienia';
            cla(app.UIAxes)
            cla(app.UIAxes2)
            cla(app.UIAxes3)
            cla(app.UIAxes4)
            app.xi;
            app.yi;
            app.obraz;
            app.dname;
            app.obraz;
            app.first;
            app.second;
            app.aktualny;
            app.nowy3;
            app.Krok=0;
        end

        % Button pushed function: WysegmentujpiepucnyButton
        function segmentujPienPlucny(app, event)
        app.Lamp2.Color='red';
        app.zLabel.Text='wybor obszaru';
        if(app.Krok>=3)
        XY=app.obraz(:,:,app.AktualnyobrazEditField_5.Value);
        try
         figure()
         imshow(XY,[])
         [xi(1),yi(1)] = getpts;
         close
        catch
         uialert(app.UIFigure,'Nie wybrano obszaru segmentacji','Invalid File');
         return;
        end
        app.zLabel.Text='Wybrany obszar segmentacji';
        J=im2uint8(app.obraz);
        Jd=double(app.obraz);           
        [x,y,z]=size(Jd);
        Jmin=min(min(Jd));   
        Jmax=max(max(Jd)); 
         s=[ceil(xi) ceil(yi) app.AktualnyobrazEditField_5.Value];
        max_dJ=app.RnicaIntensywnociSpinner.Value;
        sasiedztwo=26; 
            l_pix=0;                    % licznik pikseli obiektu
            Jw=false(x,y,z);              % binarny obraz wynikowy
            maska=false(x,y,z);           % pomocnicza macierz informujÄ…ca czy juĹĽ sprawdzono dany piksel
            kolejka=s;                  % wstawienie do kolejki punktu startowego
            
            
           app.zLabel.Text='Segmentowanie pnia plucnego 26 sasiedztwo' ;
        
        %%%%%%%%%%%%%%%%%%%%%%%%%
              while (~isempty(kolejka))                 % petla glołwna (max. 1s)
                c=kolejka(1,:,:);                         % punkt c ze szczytu kolejki do analizy
                kolejka=kolejka(2:size(kolejka,1),:,:);   % usuniecie c z kolejki
                if (~maska(c(1),c(2),c(3)))                  % czy punkt nie byl‚ jeszcze analizowany?
                    maska(c(1),c(2),c(3))=true;              % odznaczenie punktu c w masce, ...
                    if (abs(Jd(c(1),c(2),c(3)))<=max_dJ) % czy kryterium wlaczenia jest speĹ‚nione?
                        Jw(c(1),c(2),c(3))=true;             % wlaczenie punktu c do obiektu
                        sasiedzi=Fun_neighbors(c,x,y,z,sasiedztwo);       % wyznaczenie indeksow sasiadow punktu c
                        kolejka=[   kolejka;
                                    sasiedzi];          % wstawienie sasiadow do kolejki
                        l_pix=l_pix+1;                  % inkrementacja licznika pikseli
                    end
        
                end
              end
        
        %
        app
        pien=Jw(:,:,1);
        app.zLabel.Text='Łączenie obrazów';
        for i=1:1:700
          try
            XY=app.obraz(:,:,i);
            XYmask=Jw(:,:,i);
            se1=strel('disk',3,0);
            XYmask=imdilate(XYmask,se1);
            JY=int16(XYmask);
            obY=XY.*JY;
            maska3D=cat(3,Jw,obY); 
            pien=+maska3D;
          catch
          end
        end
        
        rka=app.second(:,:,1); %warstwa startowa
        full=app.second(:,:,1);
        for i=1:1:700
          try
            XY=app.second(:,:,i);
            XYmask=pien(:,:,i);
            obY=JY+XYmask;
             maska3D=cat(3,full,obY); 
            full=+maska3D;
          catch
          end
        end
        try
        app.nowy3=im2single(int16(full));
        app.nowy3=flip(app.nowy3,3);
        catch  
             uialert(app.UIFigure,'Coś poszło nie tak, spróbuj jeszcze raz!','Invalid File');
        end
        %lighting flat
        %lighting none
       else
 uialert(app.UIFigure,'Należy najpierw wykonać wcześniejsze kroki.','Warning','Icon','warning');
        end
        app.zLabel.Text='Zakonczenie segmentacji';
        app.Lamp2.Color='green';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, {'Rekonstrukcja oskrzeli '; ''})
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @suwak1, true);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @suwak1next, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 57.0763221153846;
            app.Krok1WybrzdjipniapucnegoLabel = uilabel(app.GridLayout);
            app.Krok1WybrzdjipniapucnegoLabel.Text = 'Krok 1: Wybór zdjęć i pnia płucnego';
            app.Rekonstrukcja3DoskrzeliLabel = uilabel(app.GridLayout);
            app.Rekonstrukcja3DoskrzeliLabel.Text = 'Rekonstrukcja 3D oskrzeli';
            app.GraniceprzedziauLabel = uilabel(app.GridLayout);
            app.GraniceprzedziauLabel.Text = 'Granice przedziału:';
            app.DodajFolderButton = uibutton(app.GridLayout, 'push');
            app.DodajFolderButton.ButtonPushedFcn = createCallbackFcn(app, @dodajFolder, true);
            app.DodajFolderButton.Tooltip = {'Wybierz folder zdjięć na których rozpocznie się segmentacja.'};
            app.DodajFolderButton.Text = 'Dodaj Folder';
            app.WytnijButton = uibutton(app.GridLayout, 'push');
            app.WytnijButton.ButtonPushedFcn = createCallbackFcn(app, @wytnij, true);
            app.WytnijButton.Tooltip = {'Wytnij porządaną serie zdjięć.'};
            app.WytnijButton.Text = 'Wytnij';
            app.Krok2WysegmentowaniepucLabel = uilabel(app.GridLayout);
            app.Krok2WysegmentowaniepucLabel.Text = 'Krok 2: Wysegmentowanie płuc';
            app.Krok3WysegmentowanieoskrzeliLabel = uilabel(app.GridLayout);
            app.Krok3WysegmentowanieoskrzeliLabel.Text = 'Krok 3: Wysegmentowanie oskrzeli';
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @suwak2, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Value = 54;
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.ValueChangedFcn = createCallbackFcn(app, @suwak3, true);
            app.Slider_3.MinorTicks = [];
            app.Slider_3.Value = 48.4224759615385;
            app.GenerujButton = uibutton(app.GridLayout, 'push');
            app.GenerujButton.ButtonPushedFcn = createCallbackFcn(app, @generuj1, true);
            app.GenerujButton.Tooltip = {'Rozpocznij proces segmentacji płuc.'};
            app.GenerujButton.Text = 'Generuj';
            app.GenerujButton_2 = uibutton(app.GridLayout, 'push');
            app.GenerujButton_2.ButtonPushedFcn = createCallbackFcn(app, @generuj2, true);
            app.GenerujButton_2.Tooltip = {'Generuj proces segmentacji oskrzelików.'};
            app.GenerujButton_2.Text = {'Generuj'; ''};
            app.Generujobraz3DButton = uibutton(app.GridLayout, 'push');
            app.Generujobraz3DButton.ButtonPushedFcn = createCallbackFcn(app, @wynikbutton, true);
            app.Generujobraz3DButton.Tooltip = {'Wygeneruj rekonstrukcjię 3D oskrzeli.'};
            app.Generujobraz3DButton.Text = 'Generuj obraz 3D';
            app.WybierzKolorDropDownLabel = uilabel(app.GridLayout);
            app.WybierzKolorDropDownLabel.Text = 'Wybierz Kolor :';
            app.WybierzKolorDropDown = uidropdown(app.GridLayout);
            app.WybierzKolorDropDown.Items = {'blue', 'red', 'green', 'black', 'gray', 'yellow', ''};
            app.WybierzKolorDropDown.Tooltip = {'Wybór koloru struktury rekonstrykcji obrazu.'};
            app.WybierzKolorDropDown.Value = 'blue';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'Oryginalny obraz')
            xlabel(app.UIAxes2, '')
            ylabel(app.UIAxes2, '')
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.OknointensywnociLabel = uilabel(app.GridLayout);
            app.OknointensywnociLabel.Text = 'Okno intensywności';
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'Segmentowanie płuc')
            xlabel(app.UIAxes3, '')
            ylabel(app.UIAxes3, '')
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.GridLayout);
            title(app.UIAxes4, 'Segmentowanie oskrzeli')
            xlabel(app.UIAxes4, '')
            ylabel(app.UIAxes4, '')
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.Slider_4 = uislider(app.GridLayout);
            app.Slider_4.Limits = [-20000 50000];
            app.Slider_4.ValueChangedFcn = createCallbackFcn(app, @Progowanie1, true);
            app.AktualnyobrazEditField_4Label = uilabel(app.GridLayout);
            app.AktualnyobrazEditField_4Label.Text = 'Aktualny obraz';
            app.AktualnyobrazEditField_4 = uieditfield(app.GridLayout, 'numeric');
            app.AktualnyobrazEditField_4.Limits = [1 Inf];
            app.AktualnyobrazEditField_4.Tooltip = {'Aktualny obraz, który będzie użyty do obecnych operacji'};
            app.AktualnyobrazEditField_4.Value = 40;
            app.minSpinnerLabel = uilabel(app.GridLayout);
            app.minSpinnerLabel.Text = 'min';
            app.minSpinner = uispinner(app.GridLayout);
            app.minSpinner.Step = 10;
            app.minSpinner.ValueChangedFcn = createCallbackFcn(app, @minIntensive, true);
            app.minSpinner.Tooltip = {'Ustawienie minimalnej wartości intensywności wyświetlanego obrazu.'};
            app.maxSpinnerLabel = uilabel(app.GridLayout);
            app.maxSpinnerLabel.Text = 'max';
            app.maxSpinner = uispinner(app.GridLayout);
            app.maxSpinner.Step = 10;
            app.maxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxIntensive, true);
            app.maxSpinner.Tooltip = {'Ustawienie maksykalnej intensywności, wyświetlanego obrazu.'};
            app.maxSpinner.Value = 300;
            app.MinSpinnerLabel = uilabel(app.GridLayout);
            app.MinSpinnerLabel.Text = 'Min';
            app.MinSpinner = uispinner(app.GridLayout);
            app.MinSpinner.ValueChangedFcn = createCallbackFcn(app, @minUsun, true);
            app.MinSpinner.Tooltip = {'Wybranie dolnej granicy zdjęć tomograficznych.'};
            app.MinSpinner.Value = 5;
            app.MaxSpinnerLabel = uilabel(app.GridLayout);
            app.MaxSpinnerLabel.Text = 'Max';
            app.MaxSpinner = uispinner(app.GridLayout);
            app.MaxSpinner.ValueChangedFcn = createCallbackFcn(app, @maxUsun, true);
            app.MaxSpinner.Tooltip = {'Wybranie górnej granicy zdjęć tomograficznych.'};
            app.MaxSpinner.Value = 120;
            app.zLabel = uilabel(app.GridLayout);
            app.zLabel.Text = 'brak akcji';
            app.AktualnyobrazSpinnerLabel = uilabel(app.GridLayout);
            app.AktualnyobrazSpinnerLabel.Text = 'Aktualny obraz';
            app.AktualnyobrazSpinner = uispinner(app.GridLayout);
            app.AktualnyobrazSpinner.Limits = [1 Inf];
            app.AktualnyobrazSpinner.ValueChangedFcn = createCallbackFcn(app, @Suwak2zobrazka, true);
            app.AktualnyobrazSpinner.Tooltip = {'Aktualny obraz, do podglądu.'};
            app.AktualnyobrazSpinner.Value = 30;
            app.ThresholdSpinnerLabel = uilabel(app.GridLayout);
            app.ThresholdSpinnerLabel.Text = 'Threshold';
            app.ThresholdSpinner = uispinner(app.GridLayout);
            app.ThresholdSpinner.Step = 10;
            app.ThresholdSpinner.Limits = [-30000 50000];
            app.ThresholdSpinner.ValueDisplayFormat = '%.0f';
            app.ThresholdSpinner.ValueChangedFcn = createCallbackFcn(app, @threshold, true);
            app.ThresholdSpinner.Tooltip = {'Ustawienie wartości do progowania'};
            app.ThresholdSpinner.Value = 17990;
            app.AktualnyobrazSpinner_2Label = uilabel(app.GridLayout);
            app.AktualnyobrazSpinner_2Label.Text = 'Aktualny obraz';
            app.AktualnyobrazSpinner_2 = uispinner(app.GridLayout);
            app.AktualnyobrazSpinner_2.ValueChangedFcn = createCallbackFcn(app, @suwak3zobrazka, true);
            app.AktualnyobrazSpinner_2.Tooltip = {'Podgląd, aktualnego obrazu edytowanego.'};
            app.AktualnyobrazSpinner_2.Value = 30;
            app.ThresholdSpinner_2Label = uilabel(app.GridLayout);
            app.ThresholdSpinner_2Label.Text = 'Threshold';
            app.ThresholdSpinner_2 = uispinner(app.GridLayout);
            app.ThresholdSpinner_2.ValueDisplayFormat = '%.0f';
            app.ThresholdSpinner_2.ValueChangedFcn = createCallbackFcn(app, @progowaniekrok3, true);
            app.ThresholdSpinner_2.Tooltip = {'Wartość progowania obrazu.'};
            app.ThresholdSpinner_2.Value = 16253;
            app.rowSpinnerLabel = uilabel(app.GridLayout);
            app.rowSpinnerLabel.Text = 'row';
            app.rowSpinner = uispinner(app.GridLayout);
            app.rowSpinner.Limits = [1 512];
            app.rowSpinner.Value = 85;
            app.columnSpinnerLabel = uilabel(app.GridLayout);
            app.columnSpinnerLabel.Text = 'column';
            app.columnSpinner = uispinner(app.GridLayout);
            app.columnSpinner.Limits = [1 512];
            app.columnSpinner.Value = 80;
            app.toleranceSpinnerLabel = uilabel(app.GridLayout);
            app.toleranceSpinnerLabel.Text = 'tolerance';
            app.toleranceSpinner = uispinner(app.GridLayout);
            app.toleranceSpinner.Tooltip = {'wartość, okreslająca intensywność pixela, która będzie tolerowana w czasie progowania.'};
            app.toleranceSpinner.Value = 3276.75;
            app.Slider_5 = uislider(app.GridLayout);
            app.Slider_5.Limits = [-20000 50000];
            app.Slider_5.ValueChangedFcn = createCallbackFcn(app, @Progowanie3, true);
            app.Slider_5.Value = 23.4485040522202;
            app.ResetujButton = uibutton(app.GridLayout, 'push');
            app.ResetujButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetujButton.Tooltip = {'Wyzeruj ustawienia i rozpocznij od nowa program.'};
            app.ResetujButton.Text = 'Resetuj';
            app.WysegmentujpiepucnyButton = uibutton(app.GridLayout, 'push');
            app.WysegmentujpiepucnyButton.ButtonPushedFcn = createCallbackFcn(app, @segmentujPienPlucny, true);
            app.WysegmentujpiepucnyButton.Tooltip = {'Rozpicznij segmentowanie pnia plucnego.'};
            app.WysegmentujpiepucnyButton.Text = 'Wysegmentuj pień płucny';
            app.Krok4SegmentacjapniapucnegoLabel = uilabel(app.GridLayout);
            app.Krok4SegmentacjapniapucnegoLabel.Text = 'Krok 4: Segmentacja pnia płucnego';
            app.AktualnyobrazEditField_5Label = uilabel(app.GridLayout);
            app.AktualnyobrazEditField_5Label.Text = 'Aktualny obraz';
            app.AktualnyobrazEditField_5 = uieditfield(app.GridLayout, 'numeric');
            app.AktualnyobrazEditField_5.Tooltip = {'Aktualny obraz, który wybierany będzie i zaznaczany pień plucny.'};
            app.AktualnyobrazEditField_5.Value = 40;
            app.RnicaIntensywnociSpinnerLabel = uilabel(app.GridLayout);
            app.RnicaIntensywnociSpinnerLabel.Text = 'Różnica Intensywności';
            app.RnicaIntensywnociSpinner = uispinner(app.GridLayout);
            app.RnicaIntensywnociSpinner.Limits = [1 Inf];
            app.RnicaIntensywnociSpinner.Tooltip = {'Różnica intensywności, która będzie decydowała o rozroście segmentacji.'};
            app.RnicaIntensywnociSpinner.Value = 50;
            app.Lamp2 = uilamp(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Aplikacja

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end